classdef Louis_II_DEFINITIVO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        NuevaposicionButton  matlab.ui.control.Button
        PosFinalPz           matlab.ui.control.NumericEditField
        PzEditFieldLabel     matlab.ui.control.Label
        PosFinalPy           matlab.ui.control.NumericEditField
        PyEditFieldLabel     matlab.ui.control.Label
        PosFinalPx           matlab.ui.control.NumericEditField
        PxEditFieldLabel     matlab.ui.control.Label
        StopButton           matlab.ui.control.Button
        BaudRate             matlab.ui.control.NumericEditField
        BaudiosLabel         matlab.ui.control.Label
        StartButton          matlab.ui.control.Button
        Image                matlab.ui.control.Image
    end

    
    properties (Access = private)
        puerto = []; 
        L2 = 12; %Longitud eslabón 1 en cm
        L3 = 15; %Longitud eslabón 2 en cm
        PxActual = 0; 
        PyActual = 0;
        PzActual = 27; 
    end

    methods (Access = private)
        function memoria = leerPosActual(app)
            msg = ['2', ',', num2str(0), ',', num2str(0), ',', num2str(0)];
            writeline(app.puerto, msg);
            
            %Leer respuesta de la ESP32
            respuesta = readline(app.puerto);   
            respuesta = char(respuesta);
            if contains(respuesta,',') && count(respuesta,',') == 2
                    datos = sscanf(respuesta, '%f,%f,%f');  %Parsea los datos recibidos
                    q1_rad = datos(1)*pi/180;
                    q2_rad = pi/2 - datos(2)*pi/180;
                    q3_rad = -datos(3)*pi/180;    %El ángulo MDC2 es negativo al girar horario (porque desde MDC1 se ve antihorario)
    
                    if length(datos) == 3
                        app.PxActual = app.L2 * cos(q2_rad) * cos(q1_rad) + app.L3 * cos(q2_rad + q3_rad) * cos(q1_rad);
                        app.PyActual = app.L2 * cos(q2_rad) * sin(q1_rad) + app.L3 * cos(q2_rad + q3_rad) * sin(q1_rad);
                        app.PzActual = app.L2 * sin(q2_rad) + app.L3 * sin(q2_rad + q3_rad);
                    else
                            uialert(app.UIFigure, 'Error al leer la posición inicial.', 'Error', 'Icon', 'error');
                    end 
            else
                    uialert(app.UIFigure,'No ha podido leer', 'Error', 'Icon', 'error');
            end
            
            memoria = [app.PxActual, app.PyActual, app.PzActual] ;

         end
   
        function degreesTrayectoria = calcularTrayectoria(app, Px, Py, Pz)
            n = 21;     % nº de puntos en la trayectoria

            % Generamos puntos intermedios desde la posición inicial
            t = linspace(0, 1, n);
            px_trayectoria = app.PxActual + t * (Px - app.PxActual);  %es un vector
            py_trayectoria = app.PyActual + t * (Py - app.PyActual);
            pz_trayectoria = app.PzActual + t * (Pz - app.PzActual);
            
            % Calculamos ángulos para cada punto
            degreesTrayectoria = zeros(n, 3);

            for i = 1:n
                px = px_trayectoria(i);
                py = py_trayectoria(i);
                pz = pz_trayectoria(i);

                % Resolución de la cinemática inversa
                k = (px^2 + py^2 + pz^2 - app.L2^2 - app.L3^2) / (2 * app.L2 * app.L3);
                q1_rad = atan2(py, px);     %grados del MPP
                q3_rad = atan2(real(sqrt(1 - k^2)), k); %grados del MDC2
                q2_rad = atan2(pz, sqrt(px^2 + py^2)) - atan2(app.L3 * sin(q3_rad), app.L2 + app.L3 * k); %MDC1
                MDC1 = pi/2 - q2_rad;
                
                % Almacenamos los ángulos en grados
                degreesTrayectoria(i, :) = [q1_rad, MDC1, q3_rad] * 180 / pi;
            end

        end

        function trayectoria_real = enviarTrayectoria(app, degreesTrayectoria)
            n = 21;
            trayectoria_real = zeros(n, 3);

            % Enviamos ángulos punto por punto
            for i = 1:size(degreesTrayectoria, 1)
                MPP = degreesTrayectoria(i, 1);
                MDC1 = degreesTrayectoria(i, 2);
                MDC2 = degreesTrayectoria(i, 3);
                mensaje = ['1', ',', num2str(MPP), ',', num2str(MDC1), ',', num2str(MDC2)];
                writeline(app.puerto, mensaje);
                pause(0.1);         %Pausa entre envíos para evitar saturar la comunicación
                memoria = app.leerPosActual();
                trayectoria_real(i, :) = memoria;
            end
        end

        function mostrarError(~, trayectoria_deseada, trayectoria_real)
            % Calculamos el error punto a punto
            error = sqrt(sum((trayectoria_deseada - trayectoria_real).^2, 2)); %Distancia euclidiana
        
            % Generamos la gráfica
            errorFig = figure('Name', 'Visualización de Error', 'NumberTitle', 'off');
            ax = axes('Parent', errorFig);
            plot3(ax, trayectoria_deseada(:, 1), trayectoria_deseada(:, 2), trayectoria_deseada(:, 3), 'g-', 'LineWidth', 2);
            hold on;
            plot3(ax, trayectoria_real(:, 1), trayectoria_real(:, 2), trayectoria_real(:, 3), 'r--', 'LineWidth', 1.5);
            scatter3(ax, trayectoria_real(:, 1), trayectoria_real(:, 2), trayectoria_real(:, 3), 20, error, 'filled');
            hold off;
            title(ax, 'Trayectoria deseada vs. Trayectoria real');
            xlabel(ax, 'X (cm)');
            ylabel(ax, 'Y (cm)');
            zlabel(ax, 'Z (cm)');
            colorbar(ax); % Mostrar escala de error
            legend(ax, 'Trayectoria deseada', 'Trayectoria real', 'Error', 'Location', 'Best');
            grid(ax, 'on');
        end
  
        function signal = checkCoord(~, Px, Py, Pz)
            a = 3; 
            b = 9; 
            c = 5; 
            R=27;
            norm_input = sqrt(Px^2 + Py^2 + Pz^2);
            is_below_ellipsoid = (Px^2 / a^2) + (Pz^2 / b^2) + (Py^2 / c^2);

            if (Pz > 0) && (norm_input < R) && (is_below_ellipsoid > 1)
                signal = 1;
            else
                signal = -1;
            end
         end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartConnection(app, event)
            port = 'COM4';
            app.puerto.BaudRate=app.BaudRate.Value;
            app.puerto=serialport(port, app.puerto.BaudRate);
            configureTerminator(app.puerto,"CR/LF","CR/LF");
            flush(app.puerto);
            pause(0.5);
            app.leerPosActual();    
            mensaje = sprintf('Posición actual:\n\nx: %.2f\ny: %.2f\nz: %.2f', app.PxActual, app.PyActual, app.PzActual);
            uialert(app.UIFigure, mensaje, 'Posición actual');
        end

        % Button pushed function: StopButton
        function StopConnection(app, event)
            mensaje = ['0', ',', num2str(0), ',', num2str(0), ',', num2str(0)]; 
            writeline(app.puerto, mensaje);
            pause(2);
        end

        % Button pushed function: NuevaposicionButton
        function New_position(app, event)
            Px = app.PosFinalPx.Value;
            Py = app.PosFinalPy.Value;
            Pz = app.PosFinalPz.Value;
            
            signal = checkCoord(app, Px, Py, Pz);
            if signal == -1
                uialert(app.UIFigure, 'Punto fuera del área de trabajo. Introduce nuevas coordenadas', 'Error', 'Icon', 'error');
            else
                degreesTrayectoria = app.calcularTrayectoria(Px, Py, Pz);
                trayectoria_real = app.enviarTrayectoria(degreesTrayectoria);
                
                %Para mostrar el error:
                num_ptos = size(trayectoria_real, 1);
                trayectoria_deseada = [linspace(app.PxActual, Px, num_ptos)', ...
                                       linspace(app.PyActual, Py, num_ptos)', ...
                                       linspace(app.PzActual, Pz, num_ptos)'];
                app.mostrarError(trayectoria_deseada, trayectoria_real);
                mensaje = sprintf('Posición actual:\n\nx: %.2f\ny: %.2f\nz: %.2f', app.PxActual, app.PyActual, app.PzActual);
                uialert(app.UIFigure, mensaje, 'Posición actual');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'brazo robotico interfaz.jpeg');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartConnection, true);
            app.StartButton.Text = 'Start';
            app.BaudiosLabel = uilabel(app.UIFigure);
            app.BaudiosLabel.Text = 'Baudios';
            app.BaudRate = uieditfield(app.UIFigure, 'numeric');
            app.BaudRate.ValueDisplayFormat = '%.0f';
            app.BaudRate.Value = 9600;
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopConnection, true);
            app.StopButton.Text = 'Stop';
            app.PxEditFieldLabel = uilabel(app.UIFigure);
            app.PxEditFieldLabel.Text = 'Px';
            app.PosFinalPx = uieditfield(app.UIFigure, 'numeric');
            app.PyEditFieldLabel = uilabel(app.UIFigure);
            app.PyEditFieldLabel.Text = 'Py';
            app.PosFinalPy = uieditfield(app.UIFigure, 'numeric');
            app.PzEditFieldLabel = uilabel(app.UIFigure);
            app.PzEditFieldLabel.Text = 'Pz';
            app.PosFinalPz = uieditfield(app.UIFigure, 'numeric');
            app.NuevaposicionButton = uibutton(app.UIFigure, 'push');
            app.NuevaposicionButton.ButtonPushedFcn = createCallbackFcn(app, @New_position, true);
            app.NuevaposicionButton.Text = 'Nueva posicion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Louis_II_DEFINITIVO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end